classdef Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        BasicSlicerTab                  matlab.ui.container.Tab
        ZIncrementsEditFieldLabel       matlab.ui.control.Label
        ZIncrementsEditField            matlab.ui.control.NumericEditField
        ExposureTimesEditFieldLabel     matlab.ui.control.Label
        ExposureTimesEditField          matlab.ui.control.NumericEditField
        CalculationResolutionPixlesLabel  matlab.ui.control.Label
        CalculationResolutionPixlesEditField  matlab.ui.control.NumericEditField
        OutputImageResolutionEditFieldLabel  matlab.ui.control.Label
        OutputImageResolutionEditField  matlab.ui.control.NumericEditField
        RunVideoComputationButton       matlab.ui.control.Button
        SelectSTLFileButton             matlab.ui.control.Button
        SelectVideoFileButton           matlab.ui.control.Button
        DisplayHeightPixlesLabel        matlab.ui.control.Label
        DisplayHeightPixlesEditField    matlab.ui.control.NumericEditField
        DisplayWidthPixlesLabel         matlab.ui.control.Label
        DisplayHeightPixlesEditField_2  matlab.ui.control.NumericEditField
        ImageTestButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        HOMEButton_2                    matlab.ui.control.Button
        MOVETOPRINTLOCATIONButton_2     matlab.ui.control.Button
        MOTORONButton                   matlab.ui.control.Button
        MOTOROFFButton                  matlab.ui.control.Button
        StartPrintButton                matlab.ui.control.Button
        TESTFOCUSButton                 matlab.ui.control.Button
        POSTCUREButton_2                matlab.ui.control.Button
        CONNECTButton                   matlab.ui.control.Button
        DISCONNECTButton                matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        ThresholdLevelEditFieldLabel    matlab.ui.control.Label
        ThresholdLevelEditField         matlab.ui.control.NumericEditField
        DesignedandproducedbyCallumVidlerLabel  matlab.ui.control.Label
        AreaScalingCheckBox             matlab.ui.control.CheckBox
        BlackImageCheckBox              matlab.ui.control.CheckBox
        AdvancedControlsTab             matlab.ui.container.Tab
        DPRINTERCONTROLLabel            matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        HOMEButton                      matlab.ui.control.Button
        OPENIMAGEDIRECTORYButton        matlab.ui.control.Button
        START2DPRINTButton              matlab.ui.control.Button
        OPENUSBCONNECTIONButton         matlab.ui.control.Button
        CLOSEUSBCONNECTIONButton        matlab.ui.control.Button
        LampLabel                       matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        MOVETOPRINTLOCATIONButton       matlab.ui.control.Button
        DPRINTLabel                     matlab.ui.control.Label
        ExposureTimesEditField_2Label   matlab.ui.control.Label
        ExposureTimesEditField_2        matlab.ui.control.NumericEditField
        DPRINTLabel_2                   matlab.ui.control.Label
        OPENVIDEOFILEButton_3           matlab.ui.control.Button
        START3DPRINTButton              matlab.ui.control.Button
        ONButton                        matlab.ui.control.Button
        OFFButton                       matlab.ui.control.Button
        FrameDurationEditFieldLabel     matlab.ui.control.Label
        FrameDurationEditField          matlab.ui.control.NumericEditField
        RotationTimeEditFieldLabel      matlab.ui.control.Label
        RotationTimeEditField           matlab.ui.control.NumericEditField
        IMAGECALIBRATEButton            matlab.ui.control.Button
        OPENFRAMEFILEButton             matlab.ui.control.Button
        FRAME3DPRINTButton              matlab.ui.control.Button
        POSTCUREButton                  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
  axis(app.UIAxes, 'equal');
            app.UIAxes.CameraViewAngleMode = 'manual'
            set(app.UIAxes,'visible','off')
        
        end

        % Callback function
        function LoadSTLFileButtonPushed(app, event)
            global file;
            global path;
            
            [file,path] = uigetfile('*.STL');
        end

        % Button pushed function: RunVideoComputationButton
        function RunVideoComputationButtonPushed(app, event)

        global STL_file; 
        global output_directory; 
        
   Computed_Axial_Lithography(STL_file, output_directory, app.ZIncrementsEditField.Value, app.ExposureTimesEditField.Value, app.CalculationResolutionPixlesEditField.Value, app.OutputImageResolutionEditField.Value, app.DisplayHeightPixlesEditField.Value, app.DisplayHeightPixlesEditField_2.Value, app.ThresholdLevelEditField.Value,app.AreaScalingCheckBox.Value,app.BlackImageCheckBox.Value)
        end

        % Value changed function: ZIncrementsEditField
        function ZIncrementsEditFieldValueChanged(app, event)
            global Layer_Height; 
            Layer_Height = app.ZIncrementsEditField.Value
            
            disp(Layer_Height)
        end

        % Value changed function: ExposureTimesEditField
        function ExposureTimesEditFieldValueChanged(app, event)
             app.ExposureTimesEditField.Value;
            
        end

        % Value changed function: 
        % CalculationResolutionPixlesEditField
        function CalculationResolutionPixlesEditFieldValueChanged(app, event)
            global Cross_Section_Resolution; 
            Cross_Section_Resolution = app.CalculationResolutionPixlesEditField.Value;
            
        end

        % Value changed function: OutputImageResolutionEditField
        function OutputImageResolutionEditFieldValueChanged(app, event)
            global Output_Image_Height;
            Output_Image_Height = app.OutputImageResolutionEditField.Value;
            
        end

        % Button pushed function: SelectSTLFileButton
        function SelectSTLFileButtonPushed(app, event)
        app.UIAxes.cla; 
        
        rotate3d(app.UIAxes,'on');

            
        global STL_file; 
        global STL_path; 
            [STL_file,STL_path] = uigetfile('*.STL');
            
            fv = stlread_new(STL_file);


%% Render
% The model is rendered with a PATCH graphics object. We also add some dynamic
% lighting, and adjust the material properties to change the specular
% highlighting.

patch(app.UIAxes,fv,'FaceColor',       [1.0 1.0 1.0], ...
         'EdgeColor',       'none',        ...
         'FaceLighting',    'gouraud',     ...
         'AmbientStrength', 0.40);

% Add a camera light, and tone down the specular highlighting
camlight(app.UIAxes,'headlight');
title(app.UIAxes,sprintf('Filename: %s',char(STL_file))); 

% Fix the axes scaling, and set a nice view angle
%axis(app.UIAxes,'image');


%             [stlcoords] = READ_stl(STL_file);
%             xco = squeeze( stlcoords(:,1,:) )';
%             yco = squeeze( stlcoords(:,2,:) )';
%             zco = squeeze( stlcoords(:,3,:) )'; 
% 
%             [hpat] = patch(app.UIAxes,xco,yco,zco,'white');
% 
% 
%             axis(app.UIAxes, 'equal');
%             app.UIAxes.CameraViewAngleMode = 'manual';
%             set(app.UIAxes,'visible','off')
      
           
        end

        % Callback function
        function SelectVideoFileButtonPushed(app, event)
            global output_directory; 
            output_directory = uigetdir(); 
            figure(app.UIFigure);
        end

        % Button pushed function: CONNECTButton, 
        % OPENUSBCONNECTIONButton
        function OPENUSBCONNECTIONButtonPushed(app, event)
            delete(instrfindall);
            global x; 
            x=serial('COM7','BAUD', 115200);
            fopen(x); 
           app.Lamp_2.Color = 'Green';
        end

        % Button pushed function: HOMEButton, HOMEButton_2
        function HOMEButtonPushed(app, event)
            global x; 
            
            fprintf(x,3); 
        end

        % Button pushed function: CLOSEUSBCONNECTIONButton, 
        % DISCONNECTButton
        function CLOSEUSBCONNECTIONButtonPushed(app, event)
            global x; 
            fclose(x); 
             app.Lamp_2.Color = 'red';
        end

        % Button pushed function: Button, Button_3
        function ButtonPushed(app, event)
            global x; 
            fprintf(x,4); 
        end

        % Button pushed function: Button_2, Button_4
        function Button_2Pushed(app, event)
            global x; 
            fprintf(x,5); 
        end

        % Button pushed function: MOVETOPRINTLOCATIONButton, 
        % MOVETOPRINTLOCATIONButton_2
        function MOVETOPRINTLOCATIONButtonPushed(app, event)
            global x; 
            fprintf(x,1); 
        end

        % Button pushed function: OPENIMAGEDIRECTORYButton
        function OPENIMAGEDIRECTORYButtonPushed(app, event)
            
            global working_directory; 
            
            working_directory = uigetdir('C:\Users\callu\OneDrive - Deakin University\Computed_Axial_Lithography\Images'); 
            figure(app.UIFigure);
        end

        % Button pushed function: START2DPRINTButton
        function START2DPRINTButtonPushed(app, event)
            global working_directory; 
            
            Print_2D(working_directory,app.ExposureTimesEditField_2.Value); 
        end

        % Button pushed function: OPENVIDEOFILEButton_3, 
        % SelectVideoFileButton
        function OPENVIDEOFILEButton_3Pushed(app, event)
            global video_file; 
            global video_path; 
            [video_file,video_path] = uigetfile('*.mp4');
            figure(app.UIFigure);
            
        end

        % Button pushed function: START3DPRINTButton, 
        % StartPrintButton
        function START3DPRINTButtonPushed(app, event)
                   global video_file; 
            global video_path; 
            
            global x; 
            
            play_video(video_file,video_path,x); 
        end

        % Button pushed function: MOTORONButton, ONButton
        function ONButtonPushed(app, event)
            global x; 
            
            fprintf(x,6);
     
            pause(0.5); 
            
            fprintf(x,double(app.RotationTimeEditField.Value));
            
     
        end

        % Button pushed function: MOTOROFFButton, OFFButton
        function OFFButtonPushed(app, event)
            global x; 
            
            fprintf(x,7); 
        end

        % Value changed function: FrameDurationEditField
        function FrameDurationEditFieldValueChanged(app, event)
            value = app.FrameDurationEditField.Value;
            
        end

        % Value changed function: RotationTimeEditField
        function RotationTimeEditFieldValueChanged(app, event)
            value = app.RotationTimeEditField.Value;
            
        end

        % Button pushed function: IMAGECALIBRATEButton, 
        % TESTFOCUSButton
        function IMAGECALIBRATEButtonPushed(app, event)
                    hFig = gcf;
 hAx  = gca;
 % set the figure to full screen
 set(hFig,'units','normalized','outerposition',[0 0 1 1]);
 % set the axes to full screen
 set(hAx,'Unit','normalized','Position',[0 0 1 1]);
 % hide the toolbar
 set(hFig,'menubar','none')
 % to hide the title
 set(hFig,'NumberTitle','off');
 set(hFig,'WindowState','fullscreen'); 
 set(gcf,'color','black');
    
 I = imread('Test_Focus.png'); 
 imshow(I); 


        end

        % Callback function
        function RunFrameComputationButtonPushed(app, event)
               global STL_file; 
        global output_directory; 
        
            Computed_Axial_Lithography_Frame(STL_file,output_directory,app.ZIncrementsEditField.Value,app.CalculationResolutionPixlesEditField.Value,app.OutputImageResolutionEditField.Value,app.DisplayHeightPixlesEditField.Value,app.DisplayHeightPixlesEditField_2.Value,app.ThresholdLevelEditField.Value)
        end

        % Button pushed function: OPENFRAMEFILEButton
        function OPENFRAMEFILEButtonPushed(app, event)
            global working_directory_frame; 
            
            working_directory_frame = uigetdir('C:\Users\callu\OneDrive - Deakin University\Computed_Axial_Lithography\Images'); 
            figure(app.UIFigure);
        end

        % Button pushed function: FRAME3DPRINTButton
        function FRAME3DPRINTButtonPushed(app, event)
              global working_directory_frame; 
              
              global x; 
              
            play_frame(working_directory_frame ,app.FrameDurationEditField.Value,x);
        end

        % Value changed function: ThresholdLevelEditField
        function ThresholdLevelEditFieldValueChanged(app, event)
            value = app.ThresholdLevelEditField.Value;
            
        end

        % Value changed function: DisplayHeightPixlesEditField
        function DisplayHeightPixlesEditFieldValueChanged(app, event)
            value = app.DisplayHeightPixlesEditField.Value;
            
        end

        % Value changed function: DisplayHeightPixlesEditField_2
        function DisplayHeightPixlesEditField_2ValueChanged(app, event)
            value = app.DisplayHeightPixlesEditField_2.Value;
            
        end

        % Button pushed function: ImageTestButton
        function ImageTestButtonPushed(app, event)
             global STL_file; 
        global output_directory; 
            Computed_Axial_Lithography_Test_Frame(STL_file,output_directory,app.ZIncrementsEditField.Value,app.CalculationResolutionPixlesEditField.Value,app.OutputImageResolutionEditField.Value,app.DisplayHeightPixlesEditField.Value,app.DisplayHeightPixlesEditField_2.Value,app.ThresholdLevelEditField.Value)
        end

        % Button pushed function: POSTCUREButton, POSTCUREButton_2
        function POSTCUREButtonPushed(app, event)
            
             hFig = gcf;
 hAx  = gca;
 % set the figure to full screen
 set(hFig,'units','normalized','outerposition',[0 0 1 1]);
 % set the axes to full screen
 set(hAx,'Unit','normalized','Position',[0 0 1 1]);
 % hide the toolbar
 set(hFig,'menubar','none')
 % to hide the title
 set(hFig,'NumberTitle','off');
 set(hFig,'WindowState','fullscreen'); 
 set(gcf,'color','black');
            
            black_image = ones(size(1000,1000)); 
            %black_image = padarray(black_image,[2 2],0,'both');
 
            rgbImage = cat(3, zeros(size(black_image)) , (169/255)*black_image, black_image);
            
            
            imshow(rgbImage); 


        end

        % Value changed function: AreaScalingCheckBox
        function AreaScalingCheckBoxValueChanged(app, event)
            value = app.AreaScalingCheckBox.Value;
            
        end

        % Value changed function: BlackImageCheckBox
        function BlackImageCheckBoxValueChanged(app, event)
            value = app.BlackImageCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicSlicerTab = uitab(app.TabGroup);
            app.BasicSlicerTab.Title = 'Basic Slicer';
            app.ZIncrementsEditFieldLabel = uilabel(app.BasicSlicerTab);
            app.ZIncrementsEditFieldLabel.Text = 'Z Increments';
            app.ZIncrementsEditField = uieditfield(app.BasicSlicerTab, 'numeric');
            app.ZIncrementsEditField.ValueChangedFcn = createCallbackFcn(app, @ZIncrementsEditFieldValueChanged, true);
            app.ZIncrementsEditField.Value = 300;
            app.ExposureTimesEditFieldLabel = uilabel(app.BasicSlicerTab);
            app.ExposureTimesEditFieldLabel.Text = 'Exposure Time (s)';
            app.ExposureTimesEditField = uieditfield(app.BasicSlicerTab, 'numeric');
            app.ExposureTimesEditField.ValueChangedFcn = createCallbackFcn(app, @ExposureTimesEditFieldValueChanged, true);
            app.ExposureTimesEditField.Value = 10;
            app.CalculationResolutionPixlesLabel = uilabel(app.BasicSlicerTab);
            app.CalculationResolutionPixlesLabel.Text = 'Calculation Resolution (Pixles)';
            app.CalculationResolutionPixlesEditField = uieditfield(app.BasicSlicerTab, 'numeric');
            app.CalculationResolutionPixlesEditField.ValueChangedFcn = createCallbackFcn(app, @CalculationResolutionPixlesEditFieldValueChanged, true);
            app.CalculationResolutionPixlesEditField.Value = 300;
            app.OutputImageResolutionEditFieldLabel = uilabel(app.BasicSlicerTab);
            app.OutputImageResolutionEditFieldLabel.Text = 'Output Image Resolution';
            app.OutputImageResolutionEditField = uieditfield(app.BasicSlicerTab, 'numeric');
            app.OutputImageResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @OutputImageResolutionEditFieldValueChanged, true);
            app.OutputImageResolutionEditField.Value = 300;
            app.RunVideoComputationButton = uibutton(app.BasicSlicerTab, 'push');
            app.RunVideoComputationButton.ButtonPushedFcn = createCallbackFcn(app, @RunVideoComputationButtonPushed, true);
            app.RunVideoComputationButton.Text = 'Run Video Computation';
            app.SelectSTLFileButton = uibutton(app.BasicSlicerTab, 'push');
            app.SelectSTLFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSTLFileButtonPushed, true);
            app.SelectSTLFileButton.Text = 'Select STL File';
            app.SelectVideoFileButton = uibutton(app.BasicSlicerTab, 'push');
            app.SelectVideoFileButton.ButtonPushedFcn = createCallbackFcn(app, @OPENVIDEOFILEButton_3Pushed, true);
            app.SelectVideoFileButton.Text = 'Select Video File';
            app.DisplayHeightPixlesLabel = uilabel(app.BasicSlicerTab);
            app.DisplayHeightPixlesLabel.Text = 'Display Height (Pixles)             ';
            app.DisplayHeightPixlesEditField = uieditfield(app.BasicSlicerTab, 'numeric');
            app.DisplayHeightPixlesEditField.ValueChangedFcn = createCallbackFcn(app, @DisplayHeightPixlesEditFieldValueChanged, true);
            app.DisplayHeightPixlesEditField.Value = 300;
            app.DisplayWidthPixlesLabel = uilabel(app.BasicSlicerTab);
            app.DisplayWidthPixlesLabel.Text = 'Display Width (Pixles)              ';
            app.DisplayHeightPixlesEditField_2 = uieditfield(app.BasicSlicerTab, 'numeric');
            app.DisplayHeightPixlesEditField_2.ValueChangedFcn = createCallbackFcn(app, @DisplayHeightPixlesEditField_2ValueChanged, true);
            app.DisplayHeightPixlesEditField_2.Value = 300;
            app.ImageTestButton = uibutton(app.BasicSlicerTab, 'push');
            app.ImageTestButton.ButtonPushedFcn = createCallbackFcn(app, @ImageTestButtonPushed, true);
            app.ImageTestButton.Text = 'Image Test';
            app.UIAxes = uiaxes(app.BasicSlicerTab);
            title(app.UIAxes, 'Model Viewer')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button_3 = uibutton(app.BasicSlicerTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_3.Text = '+';
            app.Button_4 = uibutton(app.BasicSlicerTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_4.Text = '-';
            app.HOMEButton_2 = uibutton(app.BasicSlicerTab, 'push');
            app.HOMEButton_2.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton_2.Text = 'HOME';
            app.MOVETOPRINTLOCATIONButton_2 = uibutton(app.BasicSlicerTab, 'push');
            app.MOVETOPRINTLOCATIONButton_2.ButtonPushedFcn = createCallbackFcn(app, @MOVETOPRINTLOCATIONButtonPushed, true);
            app.MOVETOPRINTLOCATIONButton_2.Text = 'MOVE TO PRINT LOCATION';
            app.MOTORONButton = uibutton(app.BasicSlicerTab, 'push');
            app.MOTORONButton.ButtonPushedFcn = createCallbackFcn(app, @ONButtonPushed, true);
            app.MOTORONButton.Text = 'MOTOR ON';
            app.MOTOROFFButton = uibutton(app.BasicSlicerTab, 'push');
            app.MOTOROFFButton.ButtonPushedFcn = createCallbackFcn(app, @OFFButtonPushed, true);
            app.MOTOROFFButton.Text = 'MOTOR OFF';
            app.StartPrintButton = uibutton(app.BasicSlicerTab, 'push');
            app.StartPrintButton.ButtonPushedFcn = createCallbackFcn(app, @START3DPRINTButtonPushed, true);
            app.StartPrintButton.Text = 'Start Print';
            app.TESTFOCUSButton = uibutton(app.BasicSlicerTab, 'push');
            app.TESTFOCUSButton.ButtonPushedFcn = createCallbackFcn(app, @IMAGECALIBRATEButtonPushed, true);
            app.TESTFOCUSButton.Text = 'TEST FOCUS';
            app.POSTCUREButton_2 = uibutton(app.BasicSlicerTab, 'push');
            app.POSTCUREButton_2.ButtonPushedFcn = createCallbackFcn(app, @POSTCUREButtonPushed, true);
            app.POSTCUREButton_2.Text = 'POST CURE';
            app.CONNECTButton = uibutton(app.BasicSlicerTab, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @OPENUSBCONNECTIONButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            app.DISCONNECTButton = uibutton(app.BasicSlicerTab, 'push');
            app.DISCONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEUSBCONNECTIONButtonPushed, true);
            app.DISCONNECTButton.Text = 'DISCONNECT';
            app.Lamp_2 = uilamp(app.BasicSlicerTab);
            app.ThresholdLevelEditFieldLabel = uilabel(app.BasicSlicerTab);
            app.ThresholdLevelEditFieldLabel.Text = 'Threshold Level';
            app.ThresholdLevelEditField = uieditfield(app.BasicSlicerTab, 'numeric');
            app.ThresholdLevelEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdLevelEditFieldValueChanged, true);
            app.DesignedandproducedbyCallumVidlerLabel = uilabel(app.BasicSlicerTab);
            app.DesignedandproducedbyCallumVidlerLabel.Text = 'Designed and produced by Callum Vidler  ';
            app.AreaScalingCheckBox = uicheckbox(app.BasicSlicerTab);
            app.AreaScalingCheckBox.ValueChangedFcn = createCallbackFcn(app, @AreaScalingCheckBoxValueChanged, true);
            app.AreaScalingCheckBox.Text = 'Area Scaling';
            app.BlackImageCheckBox = uicheckbox(app.BasicSlicerTab);
            app.BlackImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlackImageCheckBoxValueChanged, true);
            app.BlackImageCheckBox.Text = 'Black Image';
            app.AdvancedControlsTab = uitab(app.TabGroup);
            app.AdvancedControlsTab.Title = 'Advanced Controls';
            app.DPRINTERCONTROLLabel = uilabel(app.AdvancedControlsTab);
            app.DPRINTERCONTROLLabel.Text = '3D PRINTER CONTROL';
            app.Button = uibutton(app.AdvancedControlsTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.AdvancedControlsTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.HOMEButton = uibutton(app.AdvancedControlsTab, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.OPENIMAGEDIRECTORYButton = uibutton(app.AdvancedControlsTab, 'push');
            app.OPENIMAGEDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @OPENIMAGEDIRECTORYButtonPushed, true);
            app.OPENIMAGEDIRECTORYButton.Text = 'OPEN IMAGE DIRECTORY';
            app.START2DPRINTButton = uibutton(app.AdvancedControlsTab, 'push');
            app.START2DPRINTButton.ButtonPushedFcn = createCallbackFcn(app, @START2DPRINTButtonPushed, true);
            app.START2DPRINTButton.Text = 'START 2D PRINT';
            app.OPENUSBCONNECTIONButton = uibutton(app.AdvancedControlsTab, 'push');
            app.OPENUSBCONNECTIONButton.ButtonPushedFcn = createCallbackFcn(app, @OPENUSBCONNECTIONButtonPushed, true);
            app.OPENUSBCONNECTIONButton.Text = 'OPEN USB CONNECTION';
            app.CLOSEUSBCONNECTIONButton = uibutton(app.AdvancedControlsTab, 'push');
            app.CLOSEUSBCONNECTIONButton.ButtonPushedFcn = createCallbackFcn(app, @CLOSEUSBCONNECTIONButtonPushed, true);
            app.CLOSEUSBCONNECTIONButton.Text = 'CLOSE USB CONNECTION';
            app.LampLabel = uilabel(app.AdvancedControlsTab);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.AdvancedControlsTab);
            app.MOVETOPRINTLOCATIONButton = uibutton(app.AdvancedControlsTab, 'push');
            app.MOVETOPRINTLOCATIONButton.ButtonPushedFcn = createCallbackFcn(app, @MOVETOPRINTLOCATIONButtonPushed, true);
            app.MOVETOPRINTLOCATIONButton.Text = 'MOVE TO PRINT LOCATION';
            app.DPRINTLabel = uilabel(app.AdvancedControlsTab);
            app.DPRINTLabel.Text = '2D PRINT';
            app.ExposureTimesEditField_2Label = uilabel(app.AdvancedControlsTab);
            app.ExposureTimesEditField_2Label.Text = 'Exposure Time (s)';
            app.ExposureTimesEditField_2 = uieditfield(app.AdvancedControlsTab, 'numeric');
            app.ExposureTimesEditField_2.Value = 30;
            app.DPRINTLabel_2 = uilabel(app.AdvancedControlsTab);
            app.DPRINTLabel_2.Text = '3D PRINT';
            app.OPENVIDEOFILEButton_3 = uibutton(app.AdvancedControlsTab, 'push');
            app.OPENVIDEOFILEButton_3.ButtonPushedFcn = createCallbackFcn(app, @OPENVIDEOFILEButton_3Pushed, true);
            app.OPENVIDEOFILEButton_3.Text = 'OPEN VIDEO FILE';
            app.START3DPRINTButton = uibutton(app.AdvancedControlsTab, 'push');
            app.START3DPRINTButton.ButtonPushedFcn = createCallbackFcn(app, @START3DPRINTButtonPushed, true);
            app.START3DPRINTButton.Text = 'START 3D PRINT';
            app.ONButton = uibutton(app.AdvancedControlsTab, 'push');
            app.ONButton.ButtonPushedFcn = createCallbackFcn(app, @ONButtonPushed, true);
            app.ONButton.Text = 'ON';
            app.OFFButton = uibutton(app.AdvancedControlsTab, 'push');
            app.OFFButton.ButtonPushedFcn = createCallbackFcn(app, @OFFButtonPushed, true);
            app.OFFButton.Text = 'OFF';
            app.FrameDurationEditFieldLabel = uilabel(app.AdvancedControlsTab);
            app.FrameDurationEditFieldLabel.Text = 'Frame Duration';
            app.FrameDurationEditField = uieditfield(app.AdvancedControlsTab, 'numeric');
            app.FrameDurationEditField.ValueChangedFcn = createCallbackFcn(app, @FrameDurationEditFieldValueChanged, true);
            app.FrameDurationEditField.Value = 56;
            app.RotationTimeEditFieldLabel = uilabel(app.AdvancedControlsTab);
            app.RotationTimeEditFieldLabel.Text = 'Rotation Time';
            app.RotationTimeEditField = uieditfield(app.AdvancedControlsTab, 'numeric');
            app.RotationTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RotationTimeEditFieldValueChanged, true);
            app.RotationTimeEditField.Value = 56;
            app.IMAGECALIBRATEButton = uibutton(app.AdvancedControlsTab, 'push');
            app.IMAGECALIBRATEButton.ButtonPushedFcn = createCallbackFcn(app, @IMAGECALIBRATEButtonPushed, true);
            app.IMAGECALIBRATEButton.Text = 'IMAGE CALIBRATE';
            app.OPENFRAMEFILEButton = uibutton(app.AdvancedControlsTab, 'push');
            app.OPENFRAMEFILEButton.ButtonPushedFcn = createCallbackFcn(app, @OPENFRAMEFILEButtonPushed, true);
            app.OPENFRAMEFILEButton.Text = 'OPEN FRAME FILE';
            app.FRAME3DPRINTButton = uibutton(app.AdvancedControlsTab, 'push');
            app.FRAME3DPRINTButton.ButtonPushedFcn = createCallbackFcn(app, @FRAME3DPRINTButtonPushed, true);
            app.FRAME3DPRINTButton.Text = 'FRAME 3D PRINT';
            app.POSTCUREButton = uibutton(app.AdvancedControlsTab, 'push');
            app.POSTCUREButton.ButtonPushedFcn = createCallbackFcn(app, @POSTCUREButtonPushed, true);
            app.POSTCUREButton.Text = 'POST CURE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end